classdef CoLink_DT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        DetectedAcceptedPartLabel_2    matlab.ui.control.Label
        CooldownTextArea               matlab.ui.control.TextArea
        CooldownTextAreaLabel          matlab.ui.control.Label
        WORKINGPIECESLabel             matlab.ui.control.Label
        OperationLamp                  matlab.ui.control.Lamp
        DetectedAcceptedPartLabel      matlab.ui.control.Label
        DigitalTwinLabel               matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        AcceptedTextArea               matlab.ui.control.TextArea
        AcceptedTextAreaLabel          matlab.ui.control.Label
        DefectiveTextArea              matlab.ui.control.TextArea
        DefectiveTextAreaLabel         matlab.ui.control.Label
        StartDigitalTwinSwitch         matlab.ui.control.ToggleSwitch
        CenterPanel                    matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        GeneralTab                     matlab.ui.container.Tab
        ResolutionDropDown             matlab.ui.control.DropDown
        ResolutionDropDownLabel        matlab.ui.control.Label
        SelectionDropDown              matlab.ui.control.DropDown
        SelectionDropDownLabel         matlab.ui.control.Label
        RefreshButton_2                matlab.ui.control.Button
        RefreshButton                  matlab.ui.control.Button
        CAMERASETTINGSLabel            matlab.ui.control.Label
        LiveVideoFeedSwitch            matlab.ui.control.Switch
        LiveVideoFeedSwitchLabel       matlab.ui.control.Label
        AnnotationsSwitch              matlab.ui.control.Switch
        AnnotationsSwitchLabel         matlab.ui.control.Label
        LampDT_PLC                     matlab.ui.control.Lamp
        GPUReadyLabel_3                matlab.ui.control.Label
        LampDT_op                      matlab.ui.control.Lamp
        GPUReadyLabel_2                matlab.ui.control.Label
        AdvancedTab                    matlab.ui.container.Tab
        PLCConnectionLabel             matlab.ui.control.Label
        ReconnectButton                matlab.ui.control.Button
        DetectionFPSTextArea           matlab.ui.control.TextArea
        DetectionFPSTextAreaLabel      matlab.ui.control.Label
        AnimationFPSTextArea           matlab.ui.control.TextArea
        AnimationFPSTextAreaLabel      matlab.ui.control.Label
        RampAreaFlagLamp               matlab.ui.control.Lamp
        RampAreaFlagLampLabel          matlab.ui.control.Label
        FinalAreaFlagLamp              matlab.ui.control.Lamp
        FinalAreaFlagLampLabel         matlab.ui.control.Label
        HighlightRampAreaSwitch        matlab.ui.control.Switch
        HighlightRampAreaSwitchLabel   matlab.ui.control.Label
        HighlightFinalAreaSwitch       matlab.ui.control.Switch
        HighlightFinalAreaSwitchLabel  matlab.ui.control.Label
        CloseApplicationButton         matlab.ui.control.StateButton
        ToggleLiveVideoFeedtoturnonLabel  matlab.ui.control.Label
        LiveVideoFeedLabel             matlab.ui.control.Label
        UIAxes_Cam                     matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        %% Initializing properties that will be used throughout the app
        %% Information from PLC
        PLC_flag_senSpawn; % Initial sensor in the chute for metal piece
        PLC_classVer; % Classification verification 
        PLC_countAccept; % Count for how many accepted parts are processed - OR MV for secondary check
        PLC_countDefect; % Count for how many defective parts are processed - OR MV for secondary check
        % Statuses for the actuators
        internal_rejectBlock; % Has the information about orientation and rejecting block

        %% Non-MV Animation
        flag_inForVerPath; % Flag used to check Forward-Verification Path
        flag_inVerFinPath; % Flag used to check Verification-Final Path
%         flag_changeOrient; % Flag used to change the orientation

        i_ForVerPath; % Index to keep track of Forward-Verification Path
        i_VerFinPath; % Index to keep track of Verification-Final Path

        %% Standby block location
        block_standby; % Array containing standby location

        %% Machine Vision
        impModel; % Imported model
        bboxes; % bboxes of predictions
        scores; % confidence scores of predictions
        labels; % Class labels of predictions

        % For Final Handling Area - Safety
        flag_detect; % Internal flag to check whether detect was being triggered
        
        bbox_finalArea; % Ramp of final area [x y width height]
        coords_finalArea; % Coordiantes for final area [x_min, y_min, x_max, y_max]
        xv_finalArea; % X-coords of polygon for final area
        yv_finalArea; % Y-coords of polygon for final area
        in_finalArea; % Array that contains all predictions and whether they are inside or outside final area
%         t_safeCheck_FA; % Timer that starts as soon as bbox satisfies conditions within final area
        flag_safeDelay_FA; % Flag that determines whether a delay is in progress for final area
        bool_finalArea; % Array that contains info if bbox satisfies FA conditions
        flag_rejectDelay_FA; % Flag for if a rejected piece is waitinig to be removed while ramp is full

        bbox_topRamp; % Bbox of top ramp area [x y width height]
        bbox_midRamp; % Bbox of middle ramp area [x y width height]
        bbox_botRamp; % Bbox of bottom ramp area [x y width height]
        coords_topRamp; % Coordinates for top ramp area [x_min, y_min, x_max, y_max]
        coords_midRamp; % Coordinates for middle ramp area [x_min, y_min, x_max, y_max]
        coords_botRamp; % Coordinates for bottom ramp area [x_min, y_min, x_max, y_max]
        flag_recheckTopRamp; % Flag to check the top ramp again
        
        bbox_rampArea; % Bbox of ramp area [x y width height]
        coords_rampArea; % Coordinates for ramp area [x_min, y_min, x_max, y_max]
        segment_rampArea; % Segmented coordinates for spawning purposes
        rampCapacity; % Ramp Capacity
        flag_safeDelay_RA; % Flag that determines whether a delay is in progress for final area
        bool_rampArea; % Array that contains info if bbox satisfies RA conditions
        buff_rampArea; % Buffer to only spawn when it is initially there 


        t_checkLimit; % Time limit that serves as a delay to unpause
        t_safeCheck_FA; % Timer used for Unpause Process
        t_safeCheck_RA;
        t_safeCheckReject_FA; 
        t_topRamp; % Timer to check the top ramp again
        t_limTopRamp; % Time limit to recheck top    


        %% Setting up VR world
        world; % VR World
        fig_vr; % VR Figure

        %% Creating the vrnode object for all the manipulatable nodes for DT

        % Actuators
        actFor; % Forward Actuator
        actVer; % Checker
        actTranf; % Transfer Actuator
        actRej; % Rejection Actuator

        % Blocks
        block_1; % Used for non-mv animation with actuators
        block_2; % Used for ramp area
        block_3; % Used for ramp area
        block_4; % Used for ramp area
        block_5;

        %% Animation arrays
        actFor_3D_ON; % Array for Forward Actuator
        actVer_3D_ON; % Array for Verification Actuator
        actTranf_3D_ON; % Array for Transfer Actuator
        actRej_3D_ON; % Array for Rejection Actuator

        % Array for Blocks in the pathway
        path_ForVer; % Array for Forward-Verification path
        path_VerFin_accept; % Array for Accepted Verification-Final path
        path_VerFin_defect; % Array for Defective Verification-Final path

        %% Index information 
        % To keep track of where in the animation process the model is in
        i_actFor; % Forward
        i_actVer; % Verificaiton
        i_actTranf; % Transfer
        i_actRej; % Rejection   
        

    end
    
    properties (Access = public)
        %% OPCUA Information
        m_tags;num_MEM_tags;
        i_tags;num_INPUT_tags;
        o_tags;num_OUTPUT_tags;
        t_tags;num_TIMER_tags;
        tag_MEM_map;
        tag_INPUT_map;
        tag_OUTPUT_map;
        tag_TIMER_map;
        m_key;i_key;o_key;t_key;
       
        %PLC "Real Time"
        %Output Tracking
        feeder_cylinder_output;
        position_cylinder_output;
        transfer_cylinder_output;
        rejection_cylinder_output;
        %Input Tracking
        workpiece_sense_input;
        % Counter Tracking
        counter_working_piece_good;
        counter_working_piece_bad;
        % Workpiece Assessment and pause elements
        WP_accept_orientation;
        ramp_full;
        
    end
    
    
    methods (Access = private)
       
        
        function main(app)

            %% Resetting the cam to the selected value
            cam = webcam(app.SelectionDropDown.Value);
            cam.Resolution = app.ResolutionDropDown.Value;

            while app.StartDigitalTwinSwitch.Value==1

                % Output PLC

                app.feeder_cylinder_output = app.tag_OUTPUT_map('Q_FEEDER_CYL').rs(app.tag_OUTPUT_map('Q_FEEDER_CYL'));
                app.position_cylinder_output = app.tag_OUTPUT_map('Q_POSITION_CYL').rs(app.tag_OUTPUT_map('Q_POSITION_CYL'));
                app.transfer_cylinder_output = app.tag_OUTPUT_map('Q_TRANSFER_CYL').rs(app.tag_OUTPUT_map('Q_TRANSFER_CYL'));
                app.rejection_cylinder_output = app.tag_OUTPUT_map('Q_REJECTION_CYL').rs(app.tag_OUTPUT_map('Q_REJECTION_CYL'));                

                % Input PLC
                app.workpiece_sense_input = app.tag_INPUT_map('I_WP_SENSE').rs(app.tag_INPUT_map('I_WP_SENSE'));
                
                % Counter Assessment PLC
                app.counter_working_piece_good = app.tag_MEM_map('INT_WP_GOOD').rs(app.tag_MEM_map('INT_WP_GOOD'));
                app.counter_working_piece_bad = app.tag_MEM_map('INT_WP_BAD').rs(app.tag_MEM_map('INT_WP_BAD'));

                % Write to Counters
                app.AcceptedTextArea.Value = num2str(app.counter_working_piece_good);
                app.DefectiveTextArea.Value = num2str(app.counter_working_piece_bad);

                % Workpiece Assessment PLC
                app.WP_accept_orientation = app.tag_MEM_map('M_DT_WP_GOOD').rs(app.tag_MEM_map('M_DT_WP_GOOD'));
                app.ramp_full = app.tag_MEM_map('M_DT_RAMP_FULL').rs(app.tag_MEM_map('M_DT_RAMP_FULL'));
                
                % Reset arrays and flags to re-populate them each iteration
                % Mainly for unpausing mechanic
                % Reset arrays
                app.bool_rampArea =[];
                app.bool_finalArea =[];
              

                %% Checking stop condition (whether DT switch is off)
                if (app.StartDigitalTwinSwitch.Value == 0)

                    break
                
                end

                %% Check spawn
                checkSpawn(app);

                %% Getting MV predictions
                % Getting snapshot from camera
                snap_image = snapshot(cam);

                if app.flag_detect == 0

                    tic
                    [app.bboxes,app.scores,app.labels] = detect(app.impModel,snap_image,"ExecutionEnvironment","gpu","SelectStrongest",true);

                    time_mv = 1/toc;
                    app.DetectionFPSTextArea.Value = sprintf('%0.1f',time_mv);

                    % Replacing labels with either 'Accepted' or
                    % 'Defected'
                    app.labels(app.labels == 'base_lip_bottom') = 'Accepted';
                    app.labels(app.labels == 'base_lip_top') = 'Defective';
                    app.labels(app.labels == 'base_side') = 'Defective';

                    % Changing confidence to percentages
                    app.scores = app.scores*100;

                    % Check if bboxes are inside the final handling area
                    checkFinalArea(app);

                    % Check if bboxes are inside ramp area
                    checkRampArea(app);

                    % Check for unpause
                    unpauseProcess(app);
                    
                    % Raise the flag to indicate detections ready
                    app.flag_detect = 1;

                end

                %% Update 3D Representation

                tic
                updateAnim(app);
                app.AnimationFPSTextArea.Value = sprintf('%0.1f',(1/toc));
               
                
                %% If only video feed is wanted
                if (app.LiveVideoFeedSwitch.Value == 1) && (app.StartDigitalTwinSwitch.Value == 1)
                    app.ToggleLiveVideoFeedtoturnonLabel.Visible = 'off';
                    image_annotated = snap_image;

                    %% If predictions are wanted
                    if (app.AnnotationsSwitch.Value == 1)

                        % Annotating image
    %                     conf_val = [app.scores];
                        conf_lab = [app.labels];
                        label_str = cell(1,size(conf_lab,1));
                        
                        for ii=1:size(conf_lab,1)
    %                             label_str{1,ii} = [sprintf('%s', conf_lab(ii)) '- Confidence: ' num2str(conf_val(ii), '%0.0f') '%'];
                            label_str{1,ii} = [sprintf('%s', conf_lab(ii))];
                        end

                        image_annotated = insertObjectAnnotation(image_annotated,'rectangle',app.bboxes,label_str,FontSize=20,LineWidth=3);

                    end
    
                    %% If want to highlight final area
                    if(app.HighlightFinalAreaSwitch.Value == 1)

                        image_annotated = insertObjectAnnotation(image_annotated,'rectangle',app.bbox_finalArea,'Final Handling Area',FontSize=20,LineWidth = 3,Color='cyan');

                    end    
    
                    %% If want to highlight ramp area
                    if(app.HighlightRampAreaSwitch.Value == 1) && (app.StartDigitalTwinSwitch.Value == 1)

                        image_annotated = insertObjectAnnotation(image_annotated,'rectangle',app.bbox_topRamp,'Top Ramp',FontSize=20,LineWidth = 3,Color='magenta');
%                         image_annotated = insertObjectAnnotation(image_annotated,'rectangle',app.bbox_midRamp,'Mid Ramp',FontSize=20,LineWidth = 3,Color='blue');
%                         image_annotated = insertObjectAnnotation(image_annotated,'rectangle',app.bbox_botRamp,'Bot Ramp',FontSize=20,LineWidth = 3,Color='red'); 

                    end

                    %% Show annotated image
                    image(app.UIAxes_Cam,image_annotated);                    
    
                % Turn everything else off
                else
                    
                    % Resetting Camera Image
                    resetCamImage(app);                    

                end

            end
            
        end


        function resetCamImage(app)
            % Function that resets the live camera feed to a default image
            image_cam = imread(".\GUI\Images\White.PNG");
            image(app.UIAxes_Cam,image_cam);
            app.ToggleLiveVideoFeedtoturnonLabel.Visible ="on";
        end

        
        function updateAnim(app)
            

            %% Non-MV animation updates
            % Getting current locations

            % ---------------------------------------           
            % For Non-MV Animation for work pieces

            % Check for sensor information if a block is detected in the
            % chute
            if app.workpiece_sense_input == 1

                % Raise flag
                app.flag_inForVerPath = 1;
            
            end

            % ---------------------------------------           
            % Updating actuator locations
            % NOTE: the origin of coordinate system starts at upper left of station
            
            % ---------------------------------------
            % For Forward Actuator
            % Main axis of actuation: Z-axis
            % if statement using location and switch value
            if (app.feeder_cylinder_output == true) ...
                    && (app.actFor.translation(3) > app.actFor_3D_ON(end))

                % Update Forward actuator location if less than max
%                 if (app.i_actFor < size(app.actFor_3D_ON,2))

                    app.actFor.translation = [app.actFor_3D_ON(1,app.i_actFor), ...
                        app.actFor_3D_ON(2,app.i_actFor), ...
                        app.actFor_3D_ON(3,app.i_actFor)];

                % Check if piece is in the first Forward-Verification path
                if (app.flag_inForVerPath == 1)

                    % Increment if max index is not reached 
                    % and location difference between actuator and block
                    % is less than distance threshold

                    push_threshold = 55; % Increased threshold once animations have been quickened (original was 55)
                    
                    if(app.i_ForVerPath < size(app.path_ForVer,2)) ... 
                            && (abs(app.path_ForVer(3,app.i_ForVerPath) - app.actFor_3D_ON(3,app.i_actFor)) <= push_threshold)

                        app.i_ForVerPath = app.i_ForVerPath + 1;
                    end

                    % Update block locations
                    app.block_1.translation = ...
                        [app.path_ForVer(1,app.i_ForVerPath),...
                        app.path_ForVer(2,app.i_ForVerPath),...
                        app.path_ForVer(3,app.i_ForVerPath)];

                    % Stop Condition
                    % If block index is greater than the max size of
                    % location array
                    % Note: Added a 3 to compensate for offset
                    if ((app.i_ForVerPath + 0) >= size(app.path_ForVer,2))
                        
                        % Restart the next index
                        app.i_VerFinPath = 1;

                        % Lower the For-Ver flag
                        app.flag_inForVerPath = 0;

                        % Raise the Ver-Fin flag
                        app.flag_inVerFinPath = 1;

                    end                     

                end                

                % Increment until max index
                if (app.i_actFor < size(app.actFor_3D_ON,2))

                    app.i_actFor = app.i_actFor + 1;

                end

%             reset condition for ON to OFF phase
            elseif (app.feeder_cylinder_output == 0) ...
                    && (app.actFor.translation(3) < app.actFor_3D_ON(1))

                app.actFor.translation = [app.actFor_3D_ON(1,app.i_actFor), ...
                app.actFor_3D_ON(2,app.i_actFor), ...
                app.actFor_3D_ON(3,app.i_actFor)];

                % Decrement until first index
                if (app.i_actFor > 1)

                    app.i_actFor = app.i_actFor - 1;

                end

            end

            % ---------------------------------------
            % For Verification Actuator
            % Main axis of actuation: Y-axis

            % ON Actuation
            if (app.position_cylinder_output == 1) ...
                    && (app.actVer.translation(2) > app.actVer_3D_ON(end))

                % If accepted change orientation and location
                if (app.tag_INPUT_map('I_POSTION_DWN').rs(app.tag_INPUT_map('I_POSTION_DWN')) == true)

                    app.internal_rejectBlock = 0;
                    
                    app.block_1.rotation = [1, 0, 0, 0];
                    app.block_1.translation = ...
                            [app.path_VerFin_accept(1,1),...
                            app.path_VerFin_accept(2,1),...
                            app.path_VerFin_accept(3,1)];

                    % Change block colour to show defective - GREEN
                    app.block_1.children.appearance.material.diffuseColor = [0,1,0];

                % If defective - this is default
                elseif app.internal_rejectBlock == 1

                    % Setting orientation and location if Defective
                    app.block_1.rotation = [1, 0, 0, deg2rad(180)];
                    app.block_1.translation = ...
                            [app.path_VerFin_defect(1,1),...
                            app.path_VerFin_defect(2,1),...
                            app.path_VerFin_defect(3,1)];
    
                    % Change block colour to show defective - RED
                    app.block_1.children.appearance.material.diffuseColor = [1,0,0];
    
                    app.actVer.translation = [app.actVer_3D_ON(1,app.i_actVer), ...
                        app.actVer_3D_ON(2,app.i_actVer), ...
                        app.actVer_3D_ON(3,app.i_actVer)];

                end


                % Increment until max index
                if (app.i_actVer < size(app.actVer_3D_ON,2))

                    app.i_actVer = app.i_actVer + 1;

                % Change orientation and location if at max iteration
                % Also check that a piece was actually detected in the
                % beginning
                elseif (app.i_actVer >= size(app.actVer_3D_ON,2)) ...
                        && (app.flag_inVerFinPath == 1)                  
                   

                end              

            % OFF Actuation
            elseif (app.position_cylinder_output == 0)...
                    && (app.actVer.translation(2) < app.actVer_3D_ON(1))

                app.actVer.translation = [app.actVer_3D_ON(1,app.i_actVer), ...
                    app.actVer_3D_ON(2,app.i_actVer), ...
                    app.actVer_3D_ON(3,app.i_actVer)];

                % Decrement until first index
                if (app.i_actVer > 1)

                    app.i_actVer = app.i_actVer - 1;

                end


            end
           
            % ---------------------------------------
            % For Transfer Actuator
            % Main axis of actuation: X-axis

            % ON Actuation
            if (app.transfer_cylinder_output == 1) ...
                    && (app.actTranf.translation(1) > app.actTranf_3D_ON(end))

                app.actTranf.translation = [app.actTranf_3D_ON(1,app.i_actTranf), ...
                    app.actTranf_3D_ON(2,app.i_actTranf), ...
                    app.actTranf_3D_ON(3,app.i_actTranf)];

                % Check if piece is in the second Verification-Final path
                if (app.flag_inVerFinPath == 1)

                    % Increment if max index is not reached 
                    % and location difference between actuator and block
                    % is less than distance threshold
                    push_threshold = 60;

                    % Update block locations if in accepted orientation
                    if (app.internal_rejectBlock == 0)

                        if(app.i_VerFinPath < size(app.path_VerFin_accept,2)) ...
                                && (abs(app.path_VerFin_accept(1,app.i_VerFinPath) - app.actTranf_3D_ON(1,app.i_actTranf)) <= push_threshold)

                            app.i_VerFinPath = app.i_VerFinPath + 1;

                        end

                        % Change block colour to show defective - GREEN
                        app.block_1.children.appearance.material.diffuseColor = [0,1,0];

                        app.block_1.translation = ...
                            [app.path_VerFin_accept(1,app.i_VerFinPath),...
                            app.path_VerFin_accept(2,app.i_VerFinPath),...
                            app.path_VerFin_accept(3,app.i_VerFinPath)];

                    % Update block locations if in defective orientation
                    elseif (app.internal_rejectBlock == 1)

                        if(app.i_VerFinPath < size(app.path_VerFin_defect,2)) ...
                            && (abs(app.path_VerFin_defect(1,app.i_VerFinPath) - app.actTranf_3D_ON(1,app.i_actTranf)) <= push_threshold)

                            app.i_VerFinPath = app.i_VerFinPath + 1;

                        end

                        % Change orientation
                        app.block_1.rotation = [1, 0, 0, deg2rad(180)];

                        % Change block colour to show defective - RED
                        app.block_1.children.appearance.material.diffuseColor = [1,0,0];

                        % Location info
                        app.block_1.translation = ...
                            [app.path_VerFin_defect(1,app.i_VerFinPath),...
                            app.path_VerFin_defect(2,app.i_VerFinPath),...
                            app.path_VerFin_defect(3,app.i_VerFinPath)];

                    end                        

                    % Stop Condition
                    % Else if it is GE to max size of position array
                    if ((app.i_VerFinPath + 3) >= size(app.path_VerFin_accept,2))

                    end
                    
                    
                end
                

                % Increment until max index
                if (app.i_actTranf < size(app.actTranf_3D_ON,2))
        
                    app.i_actTranf = app.i_actTranf + 1;

                end


            % OFF Actuation
            elseif (app.transfer_cylinder_output == 0)...
                    && (app.actTranf.translation(1) < app.actTranf_3D_ON(1))

                app.actTranf.translation = [app.actTranf_3D_ON(1,app.i_actTranf), ...
                    app.actTranf_3D_ON(2,app.i_actTranf), ...
                    app.actTranf_3D_ON(3,app.i_actTranf)];

                % Decrement until first index
                if (app.i_actTranf > 1)

                    app.i_actTranf = app.i_actTranf - 1;

                end

            end
                

            % ---------------------------------------           
            % For Reject Actuator
            % Main axis of actuation: Z-axis

            % ON Actuation
            if (app.rejection_cylinder_output == 1) ...
                    && (app.actRej.translation(3) < app.actRej_3D_ON(end))

                app.actRej.translation = [app.actRej_3D_ON(1,app.i_actRej), ...
                    app.actRej_3D_ON(2,app.i_actRej), ...
                    app.actRej_3D_ON(3,app.i_actRej)];

                % Increment until max index
                if (app.i_actRej < size(app.actRej_3D_ON,2))

                    app.i_actRej = app.i_actRej + 1;

                end

                % De-spawn the block if
                % 1. Iteration is at max size
                % 2. AND flag_inVerFinPath is raised
                % 3. AND Transfer actuator is at maximum iteration
                if (app.i_actRej == size(app.actRej_3D_ON,2)) ...
                        && (app.flag_inVerFinPath == 1) ...
                        && (app.i_actTranf == size(app.actTranf_3D_ON,2))

                    % Put block on standby
                    block_Standby(app,app.block_1);

                    % Reset block indices 
                    app.i_ForVerPath = 1;
                    app.i_VerFinPath = 1;
                     
                    % Lower VerFin Flag
                    app.flag_inVerFinPath = 0;

                    % Setting internal rejectBlock to default defective
                    app.internal_rejectBlock = 1;

                end

            % OFF Actuation
            elseif (app.rejection_cylinder_output == 0)...
                    && (app.actRej.translation(2) > app.actRej_3D_ON(1))

                app.actRej.translation = [app.actRej_3D_ON(1,app.i_actRej), ...
                    app.actRej_3D_ON(2,app.i_actRej), ...
                    app.actRej_3D_ON(3,app.i_actRej)];

                % Decrement until first index
                if (app.i_actRej > 1)

                    app.i_actRej = app.i_actRej - 1;

                end


            end
            

            %% Updating MV Animations

            % Updating locations for blocks at the final handling area
            % Including the area down the chute
            % Acknowledging flag for new detections
            app.flag_detect = 0;

            %% Update Virtual World
            vrdrawnow;
        end
        
        function checkFinalArea(app)

            %% Checking whether an accepted bounding box origin is within the specified
            % Final Handling Area

            % Check if bbox is not empty
            if not(isempty(app.bboxes))


                % Go through all the bboxes and check if any are in final
                % area
                for i = 1:size(app.bboxes,1)

                    % Only check for 'Accepted' workpieces 
                    if app.labels(i) == 'Accepted'

                        bool_inFinal= checkArea(app,app.coords_finalArea,app.bbox_finalArea,app.bboxes(i,:));
                        app.bool_finalArea(i) = bool_inFinal;
                    

                    % Else if it is defective put in a 0 in the bool array
                    elseif app.labels(i) == 'Defective'

                        flag_rej = checkArea(app,app.coords_finalArea,app.bbox_finalArea,app.bboxes(i,:));
                        
                        % Check if there is a rejected piece and the ramp
                        % is full
                        if (flag_rej == 1) && (any(app.bool_rampArea(:)) == 1 )

                            app.bool_finalArea(i) = 2;

                        end
                       
                    end
                    
                end

                % If there are any in there are any '1' in the array
                if(any(app.bool_finalArea(:)) == 1 )

                    % Turn corresponding LED Green and start timer
                    app.OperationLamp.Color = [0,1,0];

                    % Send Pause command to PLC
%                     %% Write to PLC tags
%                     app.tag_MEM_map('PAUSE').w2s(true,app.tag_MEM_map('PAUSE'))
                    app.tag_MEM_map('M_DT_WP_GOOD').w2s(app.tag_MEM_map('M_DT_WP_GOOD'),true);

                    % Pause 
                    % Start timer and raise the pause flag
                    app.t_safeCheck_FA = tic;
                    app.flag_safeDelay_FA = 1;

                    app.FinalAreaFlagLamp.Color = [0,1,0];

                elseif (any(app.bool_finalArea(:)) ==  2 )

                    % Raise flag
                    app.flag_rejectDelay_FA = 1;

                    % Start reject timer to have a safe delay
                    app.t_safeCheckReject_FA = tic;

                end

            %% If there are no predictions on the screen    
            else
                
                % Clear the boolean array for final Area
                app.bool_finalArea = [];
                

            end

        end
        
        function block_Standby(app,block_vrNode)

            % Reset orientation
            block_vrNode.rotation = [1, 0, 0, 0];

            % Reset colour
            % White
            app.block_1.children.appearance.material.diffuseColor = [1,1,1];

            % Reset position
            block_vrNode.translation = [app.block_standby(1),...
                                       app.block_standby(2),...
                                       app.block_standby(3)];
            
        end
        
        function checkRampArea(app)
            % Function that checks whether the ramp is full and
            % displaying/animating
            % the appropriate number of blocks on the slide
            % NOTE: Spawns blocks only on certain iterations to reduce
            % stutering

            app.bool_rampArea = [];
                       
            % Check if bbox is not empty
            if not(isempty(app.bboxes))

                % Go through all the bboxes 
                % area
                for i = 1:size(app.bboxes,1)
    
                    cur_bbox = app.bboxes(i,:);
    
                    % Run through check through all ramp portions
                    bool_topRamp = checkArea(app,app.coords_topRamp,app.bbox_topRamp,cur_bbox);
                    bool_midRamp = checkArea(app,app.coords_midRamp,app.bbox_midRamp,cur_bbox);
                    bool_botRamp = checkArea(app,app.coords_botRamp,app.bbox_botRamp,cur_bbox);   
    
                    block_orientation = app.labels(i);
    
                        % If something is detected in top ramp
                        if (bool_topRamp == 1) 
                            
                            % If re-check flag is down
                            if app.flag_recheckTopRamp == 0
                                % Start timer
                                app.t_topRamp = tic;
        
                                % Raise re-check flag
                                app.flag_recheckTopRamp = 1;
    
                            % Else if the re-flag is raised and it timer is
                            % above the limit
                            elseif (app.flag_recheckTopRamp == 1) && (toc(uint64(app.t_topRamp)) >= app.t_limTopRamp)
        
                                % Spawn a block depending on orientation                                

                                if (block_orientation == 'Accepted') 
                                    % Change block colour to show defective -
                                    % GREEN
                                    app.block_2.children.appearance.material.diffuseColor = [0,1,0];
    
                                    app.block_2.translation = [60, 150, 130];
                                    app.block_2.rotation = [1, 0, 0, deg2rad(37.5)];
                                    
    
                                elseif (block_orientation == 'Defective') 

                                    % Change block colour to show defective - RED
                                    app.block_2.children.appearance.material.diffuseColor = [1,0,0];
    
                                    app.block_2.translation = [125, 175, 150];
                                    app.block_2.rotation = [0, -5, 15, deg2rad(180)];
    
                                end

                            end
    
                                % Put a 1 in the output boolean
                                app.bool_rampArea(i) = 1;
                           
                                % Write pause to for ramp full flag
                                app.tag_MEM_map('M_DT_RAMP_FULL').w2s(app.tag_MEM_map('M_DT_RAMP_FULL'),true);
    
                                % Pause                            
                                % Turn corresponding LED Green
                                app.OperationLamp.Color = [0,1,0];
            
                                % Start timer and raise the pause flag
                                app.t_safeCheck_RA = tic;
                                app.flag_safeDelay_RA = 1;
                                app.RampAreaFlagLamp.Color = [0,1,0];                              
        
                        end     
    
                        % Check for middle
                        if (bool_midRamp == 1)
                            
                            app.bool_rampArea(i) = 2;
                            
                            % Spawn blocks based on orientation                       
                            if (block_orientation == 'Accepted') && (not(any(app.buff_rampArea == 2)))

                                % Change block colour to show defective -
                                % GREEN
                                app.block_3.children.appearance.material.diffuseColor = [0,1,0];
    
                                app.block_3.translation = [60, 110, 180];
                                app.block_3.rotation = [1, 0, 0, deg2rad(37.5)];
    
                            elseif (block_orientation == 'Defective')

                                % Change block colour to show defective - RED
                                app.block_3.children.appearance.material.diffuseColor = [1,0,0];
    
                                app.block_3.translation = [125, 135, 200];
                                app.block_3.rotation = [0, -5, 15, deg2rad(180)];
    
                            end
    
                        end
    
                        % Check for bottom
                        if (bool_botRamp == 1)
    
                            app.bool_rampArea(i) = 3;
                            
                            % Spawn blocks based on orientation
                            if (block_orientation == 'Accepted')
                                % Change block colour to show defective -
                                % GREEN
                                app.block_4.children.appearance.material.diffuseColor = [0,1,0];
    
                                app.block_4.translation = [60, 70, 230];
                                app.block_4.rotation = [1, 0, 0, deg2rad(37.5)];
    
                            elseif (block_orientation == 'Defective') 
                                % Change block colour to show defective - RED
                                app.block_4.children.appearance.material.diffuseColor = [1,0,0];
    
                                app.block_4.translation = [125, 95, 250];
                                app.block_4.rotation = [0, -5, 15, deg2rad(180)];
    
                            end
    
                        end                        
                        
                end               


                % Store the current boolean array to buffer
                app.buff_rampArea = app.bool_rampArea;

            end

            % De-spawn conditions
            % For top spawn
            % If the previous iteration's buffer had something
            % detected, but this iteration's detection does not detect
            % anything
            if (not(any(app.bool_rampArea == 1))) 
%                 && (any(app.buff_rampArea == 1))

                % De-spawn top block
                block_Standby(app,app.block_2);

            end

            if (not(any(app.bool_rampArea == 2))) 
%                 && (any(app.buff_rampArea == 2))

                % De-spawn middle block
                block_Standby(app,app.block_3);

            end

            if (not(any(app.bool_rampArea == 3))) 
%                 && (any(app.buff_rampArea == 3))

                % De-spawn bot block
                block_Standby(app,app.block_4);

            end

        end
        
        function bool_inArea = checkArea(app,specifiedCoords,specifiedBbox,bbox)

            % Function that checks whether a certain bbox is within a
            % specified area
            % InputS: Designated area - Coordinates and Bounding Box, bbox
            % Output: A single boolean value (1-true, 0-false)

            % bbox_coords = [x_min, y_min, x_max, y_max]
            bbox_coords = [bbox(1), bbox(2), bbox(1) + bbox(3), bbox(2) + bbox(4)];

            % Checking to see if shape is fully within the final area - output 1
            if((bbox_coords(1) >= specifiedCoords(1)) && (bbox_coords(3) <= specifiedCoords(3)) && ...
                    (bbox_coords(2) >= specifiedCoords(2)) && (bbox_coords(4) <= specifiedCoords(4)))

                bool_inArea = 1;

            elseif (bboxOverlapRatio(specifiedBbox,bbox) >= 0.6)
                % Else if checking if overlap is greater than 60% -
                % Refine this number later
                bool_inArea = 1;

            else

                % Else output a 0
                bool_inArea = 0;

            end
            
        end
        
        
        function unpauseProcess(app)

            % If any of the safety delay flags are raised
            if app.flag_safeDelay_FA || app.flag_safeDelay_RA

                % Countdown case - only the FA is raised, the final area is clear
                % and the RA flag is not raised
                if app.flag_safeDelay_FA == 1 && not(any(app.bool_finalArea == 1)) && app.flag_safeDelay_RA == 0
    
                    % Writing to Unpause Countdown Label
                    app.CooldownTextArea.Value = sprintf('%0.1f',(app.t_checkLimit-toc(uint64(app.t_safeCheck_FA))));
    
                    % Turn corresponding LED Yellow and start timer
                    app.OperationLamp.Color = [1,1,0];
    
                    % For FA Reset
                    % Check if timer is over the limit, if so then reset
                    % animation and time
                    if (toc(uint64(app.t_safeCheck_FA)) >= app.t_checkLimit)     
                       
                        % Lower FA flag
                        app.flag_safeDelay_FA = 0;
                        app.FinalAreaFlagLamp.Color = [1,0,0];
        
                        % Put Block 1 on standby & reset orientation
                        block_Standby(app,app.block_1);
                        
                        % Reset block indices 
                        app.i_ForVerPath = 1;
                        app.i_VerFinPath = 1;
        
                        % Lower VerFin Flag
                        app.flag_inVerFinPath = 0;
        
                        % Reset class type
                        app.PLC_classVer = 0;
        
                        % Writing to Unpause Countdown Label
                        app.CooldownTextArea.Value = sprintf('--');
        
                        % Turn corresponding LED Red and start timer only if RA
                        % flag is not raised                
                        app.OperationLamp.Color = [1,0,0];
        
                        % Reset timer
                        app.t_safeCheck_FA = tic;
    
                        % Send PLC Unpause
                        app.tag_MEM_map('M_DT_WP_GOOD').w2s(app.tag_MEM_map('M_DT_WP_GOOD'),false);

                        % Setting internal rejectBlock to default defective
                        app.internal_rejectBlock = 1;
        
        
                    end            
    
    
                % Check if the top of ramp area is cleared and the flag is
                % raised with FA flag down
                % Reset all RA flags and timer
                elseif (not(any(app.bool_rampArea == 1)) && (app.flag_safeDelay_RA == 1)) ...
                        && (app.flag_safeDelay_FA == 0)
    
                    % Writing to Unpause Countdown Label
                    app.CooldownTextArea.Value = sprintf('%0.1f',(app.t_checkLimit-toc(uint64(app.t_safeCheck_RA))));
    
                    % Turn corresponding LED Yellow and start timer
                    app.OperationLamp.Color = [1,1,0];
    
                    % If timer is over the count for RA
                    if (toc(uint64(app.t_safeCheck_RA)) >= app.t_checkLimit) 
        
                        % Reset safe ramp flags and lamp
                        app.flag_safeDelay_RA = 0;
                        app.flag_recheckTopRamp = 0;
                        app.RampAreaFlagLamp.Color = [1,0,0];
    
                        % Writing to Unpause Countdown Label
                        app.CooldownTextArea.Value = sprintf('--');
        
                        % Turn corresponding LED Red and start timer
                        app.OperationLamp.Color = [1,0,0];

                        % De-spawn top block
                        block_Standby(app,app.block_2);
        
                        % Reset timer
                        app.t_safeCheck_RA = tic;
    
                        % Send PLC Unpause
                        app.tag_MEM_map('M_DT_RAMP_FULL').w2s(app.tag_MEM_map('M_DT_RAMP_FULL'),false);
        
                    end
    
                % Both flags are raised 
                elseif app.flag_safeDelay_FA && app.flag_safeDelay_RA
    
                    % Check if FA area is cleared 
                    if not(any(app.bool_finalArea == 1)) 

                        % Writing to Unpause Countdown Label
                        app.CooldownTextArea.Value = sprintf('%0.1f',(app.t_checkLimit-toc(uint64(app.t_safeCheck_FA))));
        
                        % Turn corresponding LED Yellow and start timer
                        app.OperationLamp.Color = [1,1,0];

                        % For FA Reset
                        % Check if timer is over the limit, if so then reset
                        % animation and time
                        if (toc(uint64(app.t_safeCheck_FA)) >= app.t_checkLimit)

                            % Lower FA flag
                            app.flag_safeDelay_FA = 0;
                            app.FinalAreaFlagLamp.Color = [1,0,0];

                            % Put Block 1 on standby & reset orientation
                            block_Standby(app,app.block_1);

                            % Reset block indices
                            app.i_ForVerPath = 1;
                            app.i_VerFinPath = 1;

                            % Lower VerFin Flag
                            app.flag_inVerFinPath = 0;

                            % Reset class type
                            app.PLC_classVer = 0;

                            % Writing to Unpause Countdown Label
                            app.CooldownTextArea.Value = sprintf('--');

                            % Turn corresponding LED Red and start timer only if RA
                            % flag is not raised
                            app.OperationLamp.Color = [1,0,0];

                            % Reset timer
                            app.t_safeCheck_FA = tic;

                            % Send PLC Unpause
                            app.tag_MEM_map('M_DT_WP_GOOD').w2s(app.tag_MEM_map('M_DT_WP_GOOD'),false);

                            % Setting internal rejectBlock to default defective
                            app.internal_rejectBlock = 1;


                        end

                    end
                    % Check if RA area is cleared 
                    if not(any(app.bool_rampArea == 1)) 

                        % Check RA timer if over 1s
                        if(toc(uint64(app.t_safeCheck_RA)) >= 1) 
            
                            % Reset safe ramp flags and lamp
                            app.flag_safeDelay_RA = 0;
                            app.flag_recheckTopRamp = 0;
                            app.RampAreaFlagLamp.Color = [1,0,0];
            
                            % Reset timer
                            app.t_safeCheck_RA = tic;

                            % De-spawn top block
                            block_Standby(app,app.block_2);

                            % Send PLC Unpause
                            app.tag_MEM_map('M_DT_RAMP_FULL').w2s(app.tag_MEM_map('M_DT_RAMP_FULL'),false);

                        end

                    end
    
                else 
    
                    % Writing to Unpause Countdown Label
                    app.CooldownTextArea.Value = sprintf('--');
    
                    % Turn corresponding LED Green
                    app.OperationLamp.Color = [0,1,0];
    
                end

            end

            
        end
       
        
        function checkSpawn(app)

            if app.workpiece_sense_input == 1

                % Set sensor value
                app.PLC_flag_senSpawn = 1;
    
                % Reset index to 1
                app.i_ForVerPath = 1;
                app.i_VerFinPath = 1;
    
                % Reset orientation and set at first location
                app.block_1.rotation = [1, 0, 0, 0];
    
                % Reset position
                app.block_1.translation = [app.path_ForVer(1),...
                                           app.path_ForVer(2),...
                                           app.path_ForVer(3)];

            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             global impModel

            %% Disabling visibility of MV vid and DT labels
            app.ToggleLiveVideoFeedtoturnonLabel.Visible = 'off';

            %% Setting all text boxes
            app.AcceptedTextArea.Value = sprintf('--');
            app.DefectiveTextArea.Value = sprintf('--');
            app.AnimationFPSTextArea.Value = sprintf('--');
            app.DetectionFPSTextArea.Value = sprintf('--');
            app.CooldownTextArea.Value = sprintf('--');

            %% Setting all lights 
            app.LampDT_PLC.Color = [1,0,0];
            app.LampDT_op.Color = [1,0,0];
            app.OperationLamp.Color = [1,0,0];

            %% Populating drop down menu to select camera

            % Populating webcam list
            availableCams = webcamlist;
            % Removing certain incompatible cameras
            availableCams(ismember(availableCams,{'ChromaCam'; 'Intel(R) RealSense(TM) Depth Camera 435 with RGB Module Depth'})) = [];
            app.SelectionDropDown.Items = availableCams;
    
            % Populating resolution list
            sampleCam = webcam(app.SelectionDropDown.Value);
            app.ResolutionDropDown.Items =  sampleCam.AvailableResolutions;

            %% Changing active working directory to DT Folder
            cd 'C:\Users\Public\Documents\DT-OPC_Integration'

            %% Importing trained model
            app.impModel = load(".\GUI\Machine Vision\Trained Model\trainedModel-738598_5111.mat").yolov4Detector;

            %% Final Handling Area Variables
            % Final Handling Area Size
            % BBox = [x y width height];
            app.bbox_finalArea = [275 5 140 140];

            % Getting coordinates for Final Area
            app.coords_finalArea = [app.bbox_finalArea(1), ...
                app.bbox_finalArea(2),...
                app.bbox_finalArea(1) + app.bbox_finalArea(3), ...
                app.bbox_finalArea(2) + app.bbox_finalArea(4)]; % Coordinates for final area [x_min, y_min , x_max, y_max]


            % Making a polygon from the size of the area
            % X-Coords
            app.xv_finalArea = [app.bbox_finalArea(1), ...
                app.bbox_finalArea(1)+app.bbox_finalArea(3),...
                app.bbox_finalArea(1)+app.bbox_finalArea(3),...
                app.bbox_finalArea(1),...
                app.bbox_finalArea(1)];

            % Y-Coords
            app.yv_finalArea = [app.bbox_finalArea(2),...
                app.bbox_finalArea(2),...
                app.bbox_finalArea(2) + app.bbox_finalArea(4),...
                app.bbox_finalArea(2) + app.bbox_finalArea(4),...
                app.bbox_finalArea(2)];

            %% Ramp Handling Area Variables
            
            % Time limit for re-checking the top ramp
            app.t_limTopRamp = 0.5; %in seconds

            % Bbox = [x y width height]
%             app.bbox_rampArea = [175, 200, 400, 160];
            app.bbox_topRamp = [275, 115, 115, 120];
            app.bbox_midRamp = [275, 200, 125, 150];
            app.bbox_botRamp = [250, 275, 150, 175];

            % Ramp capacity
            app.rampCapacity = 3;

            % Getting coordinates for Ramp Area [x_min, y_min, x_max, y_max]
            % For top
            app.coords_topRamp = [app.bbox_topRamp(1), ...
                app.bbox_topRamp(2),...
                app.bbox_topRamp(1) + app.bbox_topRamp(3), ...
                app.bbox_topRamp(2) + app.bbox_topRamp(4)];

            % For mid
            app.coords_midRamp = [app.bbox_midRamp(1), ...
                app.bbox_midRamp(2),...
                app.bbox_midRamp(1) + app.bbox_midRamp(3), ...
                app.bbox_midRamp(2) + app.bbox_midRamp(4)];

            % For bot
            app.coords_botRamp = [app.bbox_botRamp(1), ...
                app.bbox_botRamp(2),...
                app.bbox_botRamp(1) + app.bbox_botRamp(3), ...
                app.bbox_botRamp(2) + app.bbox_botRamp(4)];

            %% Declaring the pause time limit
            app.t_checkLimit = 2; % Time to check again - in seconds

            %% Importing actuator animations
            
            % Forward Actuator
            app.actFor_3D_ON = load('.\Animation\Actuators ON-OFF Animations.mat','actFor_3D_ON').actFor_3D_ON;

            % Verification Actuator
            app.actVer_3D_ON = load('.\Animation\Actuators ON-OFF Animations.mat','actVer_3D_ON').actVer_3D_ON;
            % For determining colour and orientation of block
            app.internal_rejectBlock = 1; % Default is Rejected Piece

            % Transfer Actuator
            app.actTranf_3D_ON = load('.\Animation\Actuators ON-OFF Animations.mat','actTranf_3D_ON').actTranf_3D_ON;

            % Rejection Actuator
            app.actRej_3D_ON = load('.\Animation\Actuators ON-OFF Animations.mat', 'actRej_3D_ON').actRej_3D_ON;

            %% Importing base blocks animations
            app.path_ForVer = load('.\Animation\Actuators ON-OFF Animations.mat','blockForVer_3D').blockForVer_3D;
            app.path_VerFin_accept = load('.\Animation\Actuators ON-OFF Animations.mat','blockVerFin_3D_accept').blockVerFin_3D_accept;
            app.path_VerFin_defect = load('.\Animation\Actuators ON-OFF Animations.mat','blockVerFin_3D_defect').blockVerFin_3D_defect;
            
            %% Setting up image figure
            % Fill figure with axes and remove tick labels
            title(app.UIAxes_Cam, []);
            xlabel(app.UIAxes_Cam, []);
            ylabel(app.UIAxes_Cam, []);
            app.UIAxes_Cam.XAxis.TickLabels = {};
            app.UIAxes_Cam.YAxis.TickLabels = {};
            app.UIAxes_Cam.XColor = 'w';
            app.UIAxes_Cam.YColor = 'w';

            %% Populating the images 
            resetCamImage(app);

            %% Setting up VR world
            app.world = vrworld('.\Animation\Final_DT_3D_World.x3d');

%             if isempty(app.world)
                open('.\Animation\Final_DT_3D_World.x3d');
%             end

            app.fig_vr = vrfigure(app.world);

            %% Creating the vrnode object for all the manipulatable nodes for DT

            % Actuators
            app.actFor = vrnode(app.world,'Forward_Transform_Dynamic'); % Forward Actuator
            app.actVer = vrnode(app.world,'Verification_Transform_Dynamic'); % Checker
            app.actTranf = vrnode(app.world,'Transfer_Transform_Dynamic'); % Transfer Actuator
            app.actRej = vrnode(app.world,'Rejection_Transform_Dynamic'); % Rejection Actuator

            % Base blocks
            app.block_1 = vrnode(app.world,'Square_bas1_Transform'); % Used for block animations in pathway
            app.block_2 = vrnode(app.world,'Square_bas2_Transform'); % Used for ramp area
            app.block_3 = vrnode(app.world,'Square_bas3_Transform'); % Used for ramp area
            app.block_4 = vrnode(app.world,'Square_bas4_Transform'); % Used for ramp area

            %% Initializing indices

            % Actuators
            app.i_actFor = 1;
            app.i_actVer = 1;
            app.i_actTranf = 1;
            app.i_actRej = 1;

            % Base blocks
            app.i_ForVerPath = 1;
            app.i_VerFinPath = 1;

            %% Lowering flags
                        
            % Lowering flag for detections
            app.flag_detect = 0;

            % Lowering the delay safe flag for both RA and FA
            app.flag_safeDelay_FA = 0; % Final Area
            app.flag_safeDelay_RA = 0; % Ramp Area

            % Lowering flag for square base tracking
            app.flag_inForVerPath = 0;
            app.flag_inVerFinPath = 0;

            % Lowering flag to check again
            app.flag_recheckTopRamp = 0;

            % Lowering rejected part flag
            app.flag_rejectDelay_FA = 0;

            %% Clearing arrays
            app.bool_finalArea = [];
            app.bool_rampArea = [];

            %% Set all actuator locations to OFF

            % Forward
            app.actFor.translation = [app.actFor_3D_ON(1,1),...
                                      app.actFor_3D_ON(2,1),...
                                      app.actFor_3D_ON(3,1)];

            % Verification
            app.actVer.translation = [app.actVer_3D_ON(1,1),...
                                      app.actVer_3D_ON(2,1),...
                                      app.actVer_3D_ON(3,1)];    

            % Transfer
            app.actTranf.translation = [app.actTranf_3D_ON(1,1),...
                                      app.actTranf_3D_ON(2,1),...
                                      app.actTranf_3D_ON(3,1)];

            % Rejection
            app.actRej.translation = [app.actRej_3D_ON(1,1),...
                                      app.actRej_3D_ON(2,1),...
                                      app.actRej_3D_ON(3,1)];

            %% Set all blocks to standby location and orientation

            % Initialize block standby array
            app.block_standby = [275, -50, 150];

            % Reset block orientation and location for all blocks
            block_Standby(app,app.block_1);
            block_Standby(app,app.block_2);
            block_Standby(app,app.block_3);
            block_Standby(app,app.block_4);

            %% OPC UA Server Initialization
            ServerInfo = opcuaserverinfo('opc.tcp://192.168.1.1:4840');
            uaClient = opc.ua.Client(ServerInfo);
            connect(uaClient);
            
            PLC_1_UA = findNodeByName(uaClient.Namespace, "PLC_1", '-once');
            PLC_1_UA_MEM = PLC_1_UA.Children(10);
            PLC_1_UA_INPUT = PLC_1_UA.Children(8);
            PLC_1_UA_OUTPUT = PLC_1_UA.Children(14);
            PLC_1_UA_TIMER = PLC_1_UA.Children(18);
            % Memory tag definition
            app.num_MEM_tags = length(PLC_1_UA_MEM.Children);
            app.m_tags = updateTags(app.num_MEM_tags,PLC_1_UA_MEM,uaClient);
            % Input tag definition
            app.num_INPUT_tags = length(PLC_1_UA_INPUT.Children);
            app.i_tags = updateTags(app.num_INPUT_tags,PLC_1_UA_INPUT,uaClient);
            % Output tag definition
            app.num_OUTPUT_tags = length(PLC_1_UA_OUTPUT.Children);
            app.o_tags = updateTags(app.num_OUTPUT_tags,PLC_1_UA_OUTPUT,uaClient);
            % Timer tag definition
            app.num_TIMER_tags = length(PLC_1_UA_TIMER.Children);
            app.t_tags = updateTags(app.num_TIMER_tags,PLC_1_UA_TIMER,uaClient);
            
            app.tag_MEM_map = containers.Map;
            app.tag_INPUT_map = containers.Map;
            app.tag_OUTPUT_map = containers.Map;
            app.tag_TIMER_map = containers.Map;

            for i = 2:length(app.m_tags)
                app.tag_MEM_map(app.m_tags(i).Name) =  app.m_tags(i);
            end
            for i = 2:length(app.i_tags)
                app.tag_INPUT_map(app.i_tags(i).Name) =  app.i_tags(i);
            end
            for i = 2:length(app.o_tags)
                app.tag_OUTPUT_map(app.o_tags(i).Name) =  app.o_tags(i);
            end
            for i = 2:length(app.t_tags)
                app.tag_TIMER_map(app.t_tags(i).Name) =  app.t_tags(i);
            end
            
            % Turn on LED for PLC Connection
            app.LampDT_PLC.Color = [0,1,0];
        end

        % Value changed function: StartDigitalTwinSwitch
        function StartDigitalTwinSwitchValueChanged(app, event)

            if app.StartDigitalTwinSwitch.Value== 1
                
                % Setting DT Status 
                app.LampDT_op.Color = [0,1,0];

                % Calling in main function
                main(app);

            elseif app.StartDigitalTwinSwitch.Value == 0

                %% Turning off all statuses to red when DT is turned off
                app.LampDT_op.Color = [1,0,0];

            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            %% Refreshing webcam list

            availableCams = webcamlist;
            availableCams(ismember(availableCams,{'ChromaCam'; 'Intel(R) RealSense(TM) Depth Camera 435 with RGB Module Depth'})) = [];
            app.SelectionDropDown.Items = availableCams;

        end

        % Button pushed function: RefreshButton_2
        function RefreshButton_2Pushed(app, event)
            
            %% Refreshing resolution list
            currentCam = webcam(app.SelectionDropDown.Value);
            app.ResolutionDropDown.Items =  currentCam.AvailableResolutions;

        end

        % Value changed function: CloseApplicationButton
        function CloseApplicationButtonValueChanged(app, event)
            
            close all;
            app.delete;
            
        end

        % Button pushed function: ReconnectButton
        function ReconnectButtonPushed(app, event)
            %% OPC UA Server Initialization
            ServerInfo = opcuaserverinfo('opc.tcp://192.168.1.1:4840');
            uaClient = opc.ua.Client(ServerInfo);
            connect(uaClient);
            
            PLC_1_UA = findNodeByName(uaClient.Namespace, "PLC_1", '-once');
            PLC_1_UA_MEM = PLC_1_UA.Children(10);
            PLC_1_UA_INPUT = PLC_1_UA.Children(8);
            PLC_1_UA_OUTPUT = PLC_1_UA.Children(14);
            PLC_1_UA_TIMER = PLC_1_UA.Children(18);
            % Memory tag definition
            app.num_MEM_tags = length(PLC_1_UA_MEM.Children);
            app.m_tags = updateTags(app.num_MEM_tags,PLC_1_UA_MEM,uaClient);
            % Input tag definition
            app.num_INPUT_tags = length(PLC_1_UA_INPUT.Children);
            app.i_tags = updateTags(app.num_INPUT_tags,PLC_1_UA_INPUT,uaClient);
            % Output tag definition
            app.num_OUTPUT_tags = length(PLC_1_UA_OUTPUT.Children);
            app.o_tags = updateTags(app.num_OUTPUT_tags,PLC_1_UA_OUTPUT,uaClient);
            % Timer tag definition
            app.num_TIMER_tags = length(PLC_1_UA_TIMER.Children);
            app.t_tags = updateTags(app.num_TIMER_tags,PLC_1_UA_TIMER,uaClient);
            
            app.tag_MEM_map = containers.Map;
            app.tag_INPUT_map = containers.Map;
            app.tag_OUTPUT_map = containers.Map;
            app.tag_TIMER_map = containers.Map;

            for i = 2:length(app.m_tags)
                app.tag_MEM_map(app.m_tags(i).Name) =  app.m_tags(i);
            end
            for i = 2:length(app.i_tags)
                app.tag_INPUT_map(app.i_tags(i).Name) =  app.i_tags(i);
            end
            for i = 2:length(app.o_tags)
                app.tag_OUTPUT_map(app.o_tags(i).Name) =  app.o_tags(i);
            end
            for i = 2:length(app.t_tags)
                app.tag_TIMER_map(app.t_tags(i).Name) =  app.t_tags(i);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {461, 461, 461};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {461, 461};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {299, '1x', 12};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartDigitalTwinSwitch = uiswitch(app.LeftPanel, 'toggle');
            app.StartDigitalTwinSwitch.ItemsData = [0 1];
            app.StartDigitalTwinSwitch.ValueChangedFcn = createCallbackFcn(app, @StartDigitalTwinSwitchValueChanged, true);
            app.StartDigitalTwinSwitch.Value = 0;
            app.DefectiveTextAreaLabel = uilabel(app.LeftPanel);
            app.DefectiveTextAreaLabel.Text = 'Defective';
            app.DefectiveTextArea = uitextarea(app.LeftPanel);
            app.AcceptedTextAreaLabel = uilabel(app.LeftPanel);
            app.AcceptedTextAreaLabel.Text = 'Accepted';
            app.AcceptedTextArea = uitextarea(app.LeftPanel);
            app.Image2 = uiimage(app.LeftPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'GUI', 'Images', 'CoLink.png');
            app.DigitalTwinLabel = uilabel(app.LeftPanel);
            app.DigitalTwinLabel.Text = 'Digital Twin';
            app.DetectedAcceptedPartLabel = uilabel(app.LeftPanel);
            app.DetectedAcceptedPartLabel.Text = 'Operation';
            app.OperationLamp = uilamp(app.LeftPanel);
            app.WORKINGPIECESLabel = uilabel(app.LeftPanel);
            app.WORKINGPIECESLabel.Text = 'WORKING PIECES';
            app.CooldownTextAreaLabel = uilabel(app.LeftPanel);
            app.CooldownTextAreaLabel.Text = 'Cooldown';
            app.CooldownTextArea = uitextarea(app.LeftPanel);
            app.DetectedAcceptedPartLabel_2 = uilabel(app.LeftPanel);
            app.DetectedAcceptedPartLabel_2.Text = 'Paused';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes_Cam = uiaxes(app.CenterPanel);
            title(app.UIAxes_Cam, 'Title')
            xlabel(app.UIAxes_Cam, 'X')
            ylabel(app.UIAxes_Cam, 'Y')
            zlabel(app.UIAxes_Cam, 'Z')
            colormap(app.UIAxes_Cam, 'white')
            app.LiveVideoFeedLabel = uilabel(app.CenterPanel);
            app.LiveVideoFeedLabel.Text = 'Live Video Feed';
            app.ToggleLiveVideoFeedtoturnonLabel = uilabel(app.CenterPanel);
            app.ToggleLiveVideoFeedtoturnonLabel.Text = 'Toggle ''Live Video Feed'' to turn on';
            app.CloseApplicationButton = uibutton(app.CenterPanel, 'state');
            app.CloseApplicationButton.ValueChangedFcn = createCallbackFcn(app, @CloseApplicationButtonValueChanged, true);
            app.CloseApplicationButton.Text = 'Close Application';
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.GPUReadyLabel_2 = uilabel(app.GeneralTab);
            app.GPUReadyLabel_2.Text = 'DT Operation';
            app.LampDT_op = uilamp(app.GeneralTab);
            app.GPUReadyLabel_3 = uilabel(app.GeneralTab);
            app.GPUReadyLabel_3.Text = 'PLC Connection';
            app.LampDT_PLC = uilamp(app.GeneralTab);
            app.AnnotationsSwitchLabel = uilabel(app.GeneralTab);
            app.AnnotationsSwitchLabel.Text = 'Annotations';
            app.AnnotationsSwitch = uiswitch(app.GeneralTab, 'slider');
            app.AnnotationsSwitch.ItemsData = [0 1];
            app.AnnotationsSwitch.Value = 0;
            app.LiveVideoFeedSwitchLabel = uilabel(app.GeneralTab);
            app.LiveVideoFeedSwitchLabel.Text = 'Live Video Feed';
            app.LiveVideoFeedSwitch = uiswitch(app.GeneralTab, 'slider');
            app.LiveVideoFeedSwitch.ItemsData = [0 1];
            app.LiveVideoFeedSwitch.Value = 0;
            app.CAMERASETTINGSLabel = uilabel(app.GeneralTab);
            app.CAMERASETTINGSLabel.Text = 'CAMERA SETTINGS';
            app.RefreshButton = uibutton(app.GeneralTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.RefreshButton_2 = uibutton(app.GeneralTab, 'push');
            app.RefreshButton_2.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_2Pushed, true);
            app.RefreshButton_2.Text = 'Refresh';
            app.SelectionDropDownLabel = uilabel(app.GeneralTab);
            app.SelectionDropDownLabel.Text = 'Selection';
            app.SelectionDropDown = uidropdown(app.GeneralTab);
            app.ResolutionDropDownLabel = uilabel(app.GeneralTab);
            app.ResolutionDropDownLabel.Text = 'Resolution';
            app.ResolutionDropDown = uidropdown(app.GeneralTab);
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.HighlightFinalAreaSwitchLabel = uilabel(app.AdvancedTab);
            app.HighlightFinalAreaSwitchLabel.Text = 'Highlight Final Area';
            app.HighlightFinalAreaSwitch = uiswitch(app.AdvancedTab, 'slider');
            app.HighlightFinalAreaSwitch.ItemsData = [0 1];
            app.HighlightFinalAreaSwitch.Value = 0;
            app.HighlightRampAreaSwitchLabel = uilabel(app.AdvancedTab);
            app.HighlightRampAreaSwitchLabel.Text = 'Highlight Ramp Area';
            app.HighlightRampAreaSwitch = uiswitch(app.AdvancedTab, 'slider');
            app.HighlightRampAreaSwitch.ItemsData = [0 1];
            app.HighlightRampAreaSwitch.Value = 0;
            app.FinalAreaFlagLampLabel = uilabel(app.AdvancedTab);
            app.FinalAreaFlagLampLabel.Text = 'Final Area Flag';
            app.FinalAreaFlagLamp = uilamp(app.AdvancedTab);
            app.RampAreaFlagLampLabel = uilabel(app.AdvancedTab);
            app.RampAreaFlagLampLabel.Text = 'Ramp Area Flag';
            app.RampAreaFlagLamp = uilamp(app.AdvancedTab);
            app.AnimationFPSTextAreaLabel = uilabel(app.AdvancedTab);
            app.AnimationFPSTextAreaLabel.Text = 'Animation FPS';
            app.AnimationFPSTextArea = uitextarea(app.AdvancedTab);
            app.DetectionFPSTextAreaLabel = uilabel(app.AdvancedTab);
            app.DetectionFPSTextAreaLabel.Text = 'Detection FPS';
            app.DetectionFPSTextArea = uitextarea(app.AdvancedTab);
            app.ReconnectButton = uibutton(app.AdvancedTab, 'push');
            app.ReconnectButton.ButtonPushedFcn = createCallbackFcn(app, @ReconnectButtonPushed, true);
            app.ReconnectButton.Text = 'Reconnect';
            app.PLCConnectionLabel = uilabel(app.AdvancedTab);
            app.PLCConnectionLabel.Text = 'PLC Connection';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CoLink_DT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end